classdef Problema5 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        Label                           matlab.ui.control.Label
        DesplazamientoEditField_2       matlab.ui.control.NumericEditField
        DesplazamientoEditField_2Label  matlab.ui.control.Label
        EscalamientoEditField_2         matlab.ui.control.NumericEditField
        EscalamientoEditField_2Label    matlab.ui.control.Label
        DesplazamientoEditField         matlab.ui.control.NumericEditField
        DesplazamientoEditFieldLabel    matlab.ui.control.Label
        EscalamientoEditField           matlab.ui.control.NumericEditField
        EscalamientoEditFieldLabel      matlab.ui.control.Label
        nEditField_2                    matlab.ui.control.NumericEditField
        nEditField_2Label               matlab.ui.control.Label
        nEditField                      matlab.ui.control.NumericEditField
        nEditFieldLabel                 matlab.ui.control.Label
        TransformacionesVerticalesLabel  matlab.ui.control.Label
        TransformacionesHorizontalesLabel  matlab.ui.control.Label
        IntervalodegraficacinLabel      matlab.ui.control.Label
        GraficarButton                  matlab.ui.control.Button
        fnEditField                     matlab.ui.control.EditField
        fnEditFieldLabel                matlab.ui.control.Label
        GraficadordefuncionesentiempodiscretorealesLabel  matlab.ui.control.Label
        UIAxes                          matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: GraficarButton
        function GraficarButtonPushed(app, event)
            %Función como cadena de caracteres asímbolos
            pchar = app.fnEditField.Value;
            
            % Obtengo todas las variables
            a = app.nEditField.Value;
            b = app.nEditField_2.Value;
            n = a:1:b;
            Eh = app.EscalamientoEditField.Value;
            Dh = app.DesplazamientoEditField.Value;
            Ev = app.EscalamientoEditField_2.Value;
            Dv = app.DesplazamientoEditField_2.Value;

            %Defino la función
            f1 = insertBefore(pchar, "n", "*");
            f1 = insertBefore(f1, "*n", num2str(Eh));
            f1 = insertBefore(f1,num2str(Eh), '(' );
            f1 = insertAfter(f1, "n", "+");
            f1 = insertAfter(f1, "n+", num2str(Dh));
            f1 = insertAfter(f1, num2str(Dh),')');
            f2 = str2sym(f1);
            f3 = Ev*f2 + Dv;
            f4 = sym2poly(f3)
            f = polyval(f4,n);
            stem(app.UIAxes,n,f);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Función')
            xlabel(app.UIAxes, 'n')
            ylabel(app.UIAxes, 'f[n]')
            zlabel(app.UIAxes, 'Z')
            app.GraficadordefuncionesentiempodiscretorealesLabel = uilabel(app.UIFigure);
            app.GraficadordefuncionesentiempodiscretorealesLabel.Text = 'Graficador de funciones en tiempo discreto reales ';
            app.fnEditFieldLabel = uilabel(app.UIFigure);
            app.fnEditFieldLabel.Text = 'f[n] = ';
            app.fnEditField = uieditfield(app.UIFigure, 'text');
            app.fnEditField.Value = 'n';
            app.GraficarButton = uibutton(app.UIFigure, 'push');
            app.GraficarButton.ButtonPushedFcn = createCallbackFcn(app, @GraficarButtonPushed, true);
            app.GraficarButton.Text = 'Graficar';
            app.IntervalodegraficacinLabel = uilabel(app.UIFigure);
            app.IntervalodegraficacinLabel.Text = 'Intervalo de graficación';
            app.TransformacionesHorizontalesLabel = uilabel(app.UIFigure);
            app.TransformacionesHorizontalesLabel.Text = 'Transformaciones Horizontales';
            app.TransformacionesVerticalesLabel = uilabel(app.UIFigure);
            app.TransformacionesVerticalesLabel.Text = 'Transformaciones Verticales';
            app.nEditFieldLabel = uilabel(app.UIFigure);
            app.nEditFieldLabel.Text = '-n';
            app.nEditField = uieditfield(app.UIFigure, 'numeric');
            app.nEditField.Value = -10;
            app.nEditField_2Label = uilabel(app.UIFigure);
            app.nEditField_2Label.Text = 'n';
            app.nEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.nEditField_2.Value = 10;
            app.EscalamientoEditFieldLabel = uilabel(app.UIFigure);
            app.EscalamientoEditFieldLabel.Text = 'Escalamiento';
            app.EscalamientoEditField = uieditfield(app.UIFigure, 'numeric');
            app.EscalamientoEditField.Value = 1;
            app.DesplazamientoEditFieldLabel = uilabel(app.UIFigure);
            app.DesplazamientoEditFieldLabel.Text = 'Desplazamiento';
            app.DesplazamientoEditField = uieditfield(app.UIFigure, 'numeric');
            app.EscalamientoEditField_2Label = uilabel(app.UIFigure);
            app.EscalamientoEditField_2Label.Text = 'Escalamiento';
            app.EscalamientoEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.EscalamientoEditField_2.Value = 1;
            app.DesplazamientoEditField_2Label = uilabel(app.UIFigure);
            app.DesplazamientoEditField_2Label.Text = 'Desplazamiento';
            app.DesplazamientoEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = {'La función debe de estar dada en terminos de t para que funcione, '; 'utilizando el operador los operadores ''+'', ''-'', ''*'',''/'', ''^'' y su '; 'correspondiente operador ''.''. '; 'Para graficar hay que apretar el botón graficar al terminar.'; ''};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Problema5

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end